classdef ControlUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StageAdjustmentPanel      matlab.ui.container.Panel
        LeftButton                matlab.ui.control.Button
        RightButton               matlab.ui.control.Button
        DownButton                matlab.ui.control.Button
        UpButton                  matlab.ui.control.Button
        StepLengthEditFieldLabel  matlab.ui.control.Label
        StepLengthEditField       matlab.ui.control.NumericEditField
        StatusEditFieldLabel      matlab.ui.control.Label
        StatusEditField           matlab.ui.control.EditField
        PowerAdjustmentPanel      matlab.ui.container.Panel
    end


    properties (Access = private)
        PortHandle % Description
        XMotor % Device control handle for XMotor
        YMotor % Device control handle for YMotor
        COM_Port = 'COM4' % COM Port used
        ThetaMotor %Waveplate motor
    end

    methods (Access = private)
    
        function results = MoveByStep(app,int)
            switch int
                case 0
                    Device = app.XMotor;
                    dir = 1;
                case 1
                    Device = app.XMotor;
                    dir = -1;
                case 2
                    Device = app.YMotor;
                    dir = -1;
                case 3
                    Device = app.YMotor;
                    dir = 1;
            end
            distance = dir*app.StepLengthEditField.Value;
            Device.moverelative(distance);
        end
        
        function results = func2(app)
            
        end
        
        function results = InitializeDevices(app,Devices)
            % Initializes individual devices from the Device handle, parsing addresses to functions on the stage setup.
            %for i = 1:length(Devices)
            app.XMotor = Devices(4); app.YMotor = Devices(5);
            app.ThetaMotor = Devices(2);
            app.XMotor.home(); app.YMotor.home();
            app.XMotor.waitforidle(); app.YMotor.waitforidle();
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Change app designer text because it's pretty danm inflexible
            
            app.RightButton.Text = char(8594);
            app.LeftButton.Text = char(8592);
            app.UpButton.Text = char(8593);
            app.DownButton.Text = char(8595);
            %Initializing code
            try
                app.PortHandle = ZaberPort(app.COM_Port); %initialize the zaber port object
                Devices = app.PortHandle.Devices;
                app.InitializeDevices(Devices)
                app.StatusEditField.Value = 'Good';
            catch
                app.StatusEditField.Value = 'Bad';
                f = errordlg('Could not initialize devices');
                waitfor(f);
                app.UIFigureCloseRequest();
            end
            
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.MoveByStep(0);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.MoveByStep(1);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.MoveByStep(2);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.MoveByStep(3);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try app.PortHandle.ClosePort();
                
            catch ME
                
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StageAdjustmentPanel = uipanel(app.UIFigure);
            app.StageAdjustmentPanel.Title = 'Stage Adjustment';
            app.LeftButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.UpButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.StepLengthEditFieldLabel = uilabel(app.StageAdjustmentPanel);
            app.StepLengthEditFieldLabel.Text = 'Step Length';
            app.StepLengthEditField = uieditfield(app.StageAdjustmentPanel, 'numeric');
            app.StepLengthEditField.Limits = [0 5000];
            app.StepLengthEditField.ValueDisplayFormat = '%.0f';
            app.StepLengthEditField.Value = 100;
            app.StatusEditFieldLabel = uilabel(app.StageAdjustmentPanel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.StageAdjustmentPanel, 'text');
            app.StatusEditField.Value = 'Nil';
            app.PowerAdjustmentPanel = uipanel(app.UIFigure);
            app.PowerAdjustmentPanel.Title = 'Power Adjustment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end